classdef GUIvDRAFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FetchLabel              matlab.ui.control.Label
        IRB1660IDLabel          matlab.ui.control.Label
        ZaxisLabel_2            matlab.ui.control.Label
        YaxisLabel_2            matlab.ui.control.Label
        zPlusFetch              matlab.ui.control.Button
        zMinusFetch             matlab.ui.control.Button
        yMinusFetch             matlab.ui.control.Button
        yPlusFetch              matlab.ui.control.Button
        xMinusFetch             matlab.ui.control.Button
        XaxisLabel_2            matlab.ui.control.Label
        xPlusFetch              matlab.ui.control.Button
        StatusEditField         matlab.ui.control.EditField
        StatusEditFieldLabel    matlab.ui.control.Label
        TEACHButton             matlab.ui.control.Button
        RESETFETCHButton_2      matlab.ui.control.Button
        RESETIRBButton          matlab.ui.control.Button
        RESUMEButton            matlab.ui.control.Button
        EMERGENCYSTOPButton     matlab.ui.control.Button
        ZaxisLabel              matlab.ui.control.Label
        YaxisLabel              matlab.ui.control.Label
        zPlusIRB                matlab.ui.control.Button
        zMinusIRB               matlab.ui.control.Button
        yMinusIRB               matlab.ui.control.Button
        yPlusIRB                matlab.ui.control.Button
        xMinusIRB               matlab.ui.control.Button
        XaxisLabel              matlab.ui.control.Label
        CartesianMovementLabel  matlab.ui.control.Label
        xPlusIRB                matlab.ui.control.Button
    end

    
    properties (Access = private)
        modelFetch % Where I will put the loaded fetch
        modelIRB1660ID
        
        q0_IRB = [-0.01 0 0 0 0 0 0];
        q0_Fetch = zeros(1,7);
        
        qf_irb = [-1.4    0.0000   -0.5864   -2.5211    0.0000   -1.5282   -0.0000];
       
        
        qrFetch =  [-0.9150   -0.6830    0.0895    0.0001   -0.0895    1.7775   -0.9150];
        qrIRB = [-0.0100 90*pi/180 0 90*pi/180 0 0 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
             % Code to run once GUI is loaded successfully

            hold on;
%             ax = gca;
            clear fetch;
            clear IRB;

            % Make, save and plot some robots            

            app.modelFetch = Fetch;
            app.modelFetch.model.base = (transl(0.75,3.5,0.734)*trotz(pi));
            
            app.modelFetch.model.animate(app.qrFetch)
%             plot(app.modelFetch, app.q0_Fetch,'workspace',[-2 2 -2 2 -0.5 2])            
            axis([-4 3 -2 5.5 0 4]);
            

            app.modelIRB1660ID = IRB1660ID;
            app.modelIRB1660ID.model.base = (transl(-2, 4, 1.5)*troty(pi/2));
            app.modelIRB1660ID.model.animate(app.qrIRB);
            drawnow();

%             Delete the green floor surface (need to count backwards
%             because once it is deleted the array is compressed)
%             for i = size(ax.Children,1):-1:1
%                 if strcmp(ax.Children(i).Type,'surface')
%                     delete(ax.Children(i));
%                 end
%             end
%             
            % Turn off this load button and enable the move button
%             app.MakeLoadedPumaMoveButton.Enable = true;
%             app.MakeLoadedUR5MoveButton.Enable = true;
            
        end

        % Button pushed function: RESETFETCHButton_2
        function RESETFETCHButton_2Pushed(app, event)
            qc_Fetch = app.modelFetch.model.getpos();
            qmatrix = jtraj(qc_Fetch,app.q0_Fetch,50);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelFetch.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
                
            end
        end

        % Button pushed function: RESETIRBButton
        function RESETIRBButtonPushed(app, event)
            %test with UR5
            qc_IRB = app.modelIRB1660ID.model.getpos();
            qmatrix = jtraj( qc_IRB, app.q0_IRB, 50);
            for robotStepIndex = 1:size(qmatrix,1)
                
                app.modelIRB1660ID.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
%             app.StatusEditField = 'Stopped. Press Resume to continue';
        end

        % Button pushed function: TEACHButton
        function TEACHButtonPushed(app, event)
            % opens teach for both robots (MAXIMISE WINDOW TO SEE BOTH PANELS)
            app.modelIRB1660ID.model.teach(app.modelIRB1660ID.model.getpos())
            app.modelFetch.model.teach(app.modelFetch.model.getpos())
        end

        % Button pushed function: xPlusIRB
        function xPlusIRBButtonPushed(app, event)
            q = app.modelIRB1660ID.model.getpos();
            tr = app.modelIRB1660ID.model.fkine(q).T;
            tr(1,4) = tr(1,4) + 0.01;
            newQ = app.modelIRB1660ID.model.ikcon(tr,q);
            app.modelIRB1660ID.model.animate(newQ);
        end

        % Button pushed function: xMinusIRB
        function xMinusIRBButtonPushed(app, event)
            q = app.modelIRB1660ID.model.getpos();
            tr = app.modelIRB1660ID.model.fkine(q).T;
            tr(1,4) = tr(1,4) - 0.01;
            newQ = app.modelIRB1660ID.model.ikcon(tr,q);
            app.modelIRB1660ID.model.animate(newQ);
        end

        % Button pushed function: yPlusIRB
        function yPlusIRBButtonPushed(app, event)
            q = app.modelIRB1660ID.model.getpos();
            tr = app.modelIRB1660ID.model.fkine(q).T;
            tr(2,4) = tr(2,4) + 0.01;
            newQ = app.modelIRB1660ID.model.ikcon(tr,q);
            app.modelIRB1660ID.model.animate(newQ);
        end

        % Button pushed function: yMinusIRB
        function yMinusIRBButtonPushed(app, event)
            q = app.modelIRB1660ID.model.getpos();
            tr = app.modelIRB1660ID.model.fkine(q).T;
            tr(2,4) = tr(2,4) - 0.01;
            newQ = app.modelIRB1660ID.model.ikcon(tr,q);
            app.modelIRB1660ID.model.animate(newQ);
        end

        % Button pushed function: zPlusIRB
        function zPlusIRBButtonPushed(app, event)
            q = app.modelIRB1660ID.model.getpos();
            tr = app.modelIRB1660ID.model.fkine(q).T;
            tr(3,4) = tr(3,4) + 0.01;
            newQ = app.modelIRB1660ID.model.ikcon(tr,q);
            app.modelIRB1660ID.model.animate(newQ);
        end

        % Button pushed function: zMinusIRB
        function zMinusIRBButtonPushed(app, event)
            q = app.modelIRB1660ID.model.getpos();
            tr = app.modelIRB1660ID.model.fkine(q).T;
            tr(3,4) = tr(3,4) - 0.01;
            newQ = app.modelIRB1660ID.model.ikcon(tr,q);
            app.modelIRB1660ID.model.animate(newQ);
        end

        % Button pushed function: xPlusFetch
        function xPlusFetchButtonPushed(app, event)
            q = app.modelFetch.model.getpos();
            tr = app.modelFetch.model.fkine(q).T;
            tr(1,4) = tr(1,4) + 0.01;
            newQ = app.modelFetch.model.ikcon(tr,q);
            app.modelFetch.model.animate(newQ);
        end

        % Button pushed function: xMinusFetch
        function xMinusFetchButtonPushed(app, event)
            q = app.modelFetch.model.getpos();
            tr = app.modelFetch.model.fkine(q).T;
            tr(1,4) = tr(1,4) - 0.01;
            newQ = app.modelFetch.model.ikcon(tr,q);
            app.modelFetch.model.animate(newQ);
        end

        % Button pushed function: yPlusFetch
        function yPlusFetchButtonPushed(app, event)
            q = app.modelFetch.model.getpos();
            tr = app.modelFetch.model.fkine(q).T;
            tr(2,4) = tr(2,4) + 0.01;
            newQ = app.modelFetch.model.ikcon(tr,q);
            app.modelFetch.model.animate(newQ);
        end

        % Button pushed function: yMinusFetch
        function yMinusFetchButtonPushed(app, event)
            q = app.modelFetch.model.getpos();
            tr = app.modelFetch.model.fkine(q).T;
            tr(2,4) = tr(2,4) - 0.01;
            newQ = app.modelFetch.model.ikcon(tr,q);
            app.modelFetch.model.animate(newQ);
        end

        % Button pushed function: zMinusFetch
        function zMinusFetchButtonPushed(app, event)
            q = app.modelFetch.model.getpos();
            tr = app.modelFetch.model.fkine(q).T;
            tr(3,4) = tr(3,4) - 0.01;
            newQ = app.modelFetch.model.ikcon(tr,q);
            app.modelFetch.model.animate(newQ);
        end

        % Button pushed function: zPlusFetch
        function zPlusFetchButtonPushed(app, event)
            q = app.modelFetch.model.getpos();
            tr = app.modelFetch.model.fkine(q).T;
            tr(3,4) = tr(3,4) + 0.01;
            newQ = app.modelFetch.model.ikcon(tr,q);
            app.modelFetch.model.animate(newQ);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xPlusIRB = uibutton(app.UIFigure, 'push');
            app.xPlusIRB.ButtonPushedFcn = createCallbackFcn(app, @xPlusIRBButtonPushed, true);
            app.xPlusIRB.Text = '+0.1';
            app.CartesianMovementLabel = uilabel(app.UIFigure);
            app.CartesianMovementLabel.Text = 'Cartesian Movement';
            app.XaxisLabel = uilabel(app.UIFigure);
            app.XaxisLabel.Text = 'X-axis';
            app.xMinusIRB = uibutton(app.UIFigure, 'push');
            app.xMinusIRB.ButtonPushedFcn = createCallbackFcn(app, @xMinusIRBButtonPushed, true);
            app.xMinusIRB.Text = '-0.1';
            app.yPlusIRB = uibutton(app.UIFigure, 'push');
            app.yPlusIRB.ButtonPushedFcn = createCallbackFcn(app, @yPlusIRBButtonPushed, true);
            app.yPlusIRB.Text = '+0.1';
            app.yMinusIRB = uibutton(app.UIFigure, 'push');
            app.yMinusIRB.ButtonPushedFcn = createCallbackFcn(app, @yMinusIRBButtonPushed, true);
            app.yMinusIRB.Text = '-0.1';
            app.zMinusIRB = uibutton(app.UIFigure, 'push');
            app.zMinusIRB.ButtonPushedFcn = createCallbackFcn(app, @zMinusIRBButtonPushed, true);
            app.zMinusIRB.Text = '-0.1';
            app.zPlusIRB = uibutton(app.UIFigure, 'push');
            app.zPlusIRB.ButtonPushedFcn = createCallbackFcn(app, @zPlusIRBButtonPushed, true);
            app.zPlusIRB.Text = '+0.1';
            app.YaxisLabel = uilabel(app.UIFigure);
            app.YaxisLabel.Text = 'Y-axis';
            app.ZaxisLabel = uilabel(app.UIFigure);
            app.ZaxisLabel.Text = 'Z-axis';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.Text = 'RESUME';
            app.RESETIRBButton = uibutton(app.UIFigure, 'push');
            app.RESETIRBButton.ButtonPushedFcn = createCallbackFcn(app, @RESETIRBButtonPushed, true);
            app.RESETIRBButton.Text = 'RESET IRB';
            app.RESETFETCHButton_2 = uibutton(app.UIFigure, 'push');
            app.RESETFETCHButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETFETCHButton_2Pushed, true);
            app.RESETFETCHButton_2.Text = 'RESET FETCH';
            app.TEACHButton = uibutton(app.UIFigure, 'push');
            app.TEACHButton.ButtonPushedFcn = createCallbackFcn(app, @TEACHButtonPushed, true);
            app.TEACHButton.Text = 'TEACH';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.xPlusFetch = uibutton(app.UIFigure, 'push');
            app.xPlusFetch.ButtonPushedFcn = createCallbackFcn(app, @xPlusFetchButtonPushed, true);
            app.xPlusFetch.Text = '+0.1';
            app.XaxisLabel_2 = uilabel(app.UIFigure);
            app.XaxisLabel_2.Text = 'X-axis';
            app.xMinusFetch = uibutton(app.UIFigure, 'push');
            app.xMinusFetch.ButtonPushedFcn = createCallbackFcn(app, @xMinusFetchButtonPushed, true);
            app.xMinusFetch.Text = '-0.1';
            app.yPlusFetch = uibutton(app.UIFigure, 'push');
            app.yPlusFetch.ButtonPushedFcn = createCallbackFcn(app, @yPlusFetchButtonPushed, true);
            app.yPlusFetch.Text = '+0.1';
            app.yMinusFetch = uibutton(app.UIFigure, 'push');
            app.yMinusFetch.ButtonPushedFcn = createCallbackFcn(app, @yMinusFetchButtonPushed, true);
            app.yMinusFetch.Text = '-0.1';
            app.zMinusFetch = uibutton(app.UIFigure, 'push');
            app.zMinusFetch.ButtonPushedFcn = createCallbackFcn(app, @zMinusFetchButtonPushed, true);
            app.zMinusFetch.Text = '-0.1';
            app.zPlusFetch = uibutton(app.UIFigure, 'push');
            app.zPlusFetch.ButtonPushedFcn = createCallbackFcn(app, @zPlusFetchButtonPushed, true);
            app.zPlusFetch.Text = '+0.1';
            app.YaxisLabel_2 = uilabel(app.UIFigure);
            app.YaxisLabel_2.Text = 'Y-axis';
            app.ZaxisLabel_2 = uilabel(app.UIFigure);
            app.ZaxisLabel_2.Text = 'Z-axis';
            app.IRB1660IDLabel = uilabel(app.UIFigure);
            app.IRB1660IDLabel.Text = 'IRB1660ID';
            app.FetchLabel = uilabel(app.UIFigure);
            app.FetchLabel.Text = 'Fetch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIvDRAFT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)init(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end